classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SealDropDown              matlab.ui.control.DropDown
        SealDropDownLabel         matlab.ui.control.Label
        ReproducirButton          matlab.ui.control.Button
        EspectrogramaButton       matlab.ui.control.Button
        EspectrodeMagnitudButton  matlab.ui.control.Button
        TiempoButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        TabGroup                  matlab.ui.container.TabGroup
        ParmetrosdeCapturaTab     matlab.ui.container.Tab
        ID_IDropDown              matlab.ui.control.DropDown
        ID_IDropDownLabel         matlab.ui.control.Label
        ID_ODropDown              matlab.ui.control.DropDown
        CargarPuertorButton       matlab.ui.control.Button
        LocutorDropDown           matlab.ui.control.DropDown
        LocutorDropDownLabel      matlab.ui.control.Label
        T_VmsEditField            matlab.ui.control.NumericEditField
        T_VmsEditFieldLabel       matlab.ui.control.Label
        Rp4EditField              matlab.ui.control.NumericEditField
        Rp4EditFieldLabel         matlab.ui.control.Label
        Rp3EditField              matlab.ui.control.NumericEditField
        Rp3EditFieldLabel         matlab.ui.control.Label
        Rp2EditField              matlab.ui.control.NumericEditField
        Rp2EditFieldLabel         matlab.ui.control.Label
        Rp1EditField              matlab.ui.control.NumericEditField
        Rp1EditFieldLabel         matlab.ui.control.Label
        TcEditField               matlab.ui.control.NumericEditField
        TcEditFieldLabel          matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t %Tiempo
        x %onda
        Fo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function GraficarOndaButtonPushed(app, event)
            %Parámetros del muestreador
            Fs = 8000; %Frecuencia de muestreo (Hz o muestras/seg)
            T = 1/Fs; %Periodo de muestreo (segundos o tiempo entre muestras)
            app.Fo = app.FoEditField.Value();
%             Vectores de tiempo
            app.t = 0:T:0.01-T;
            app.UIAxes.Visible = 'on';
            app.SiarribagraficocopiarloabajoButton.Visible = 'on';
            app.x = cos(2*pi*app.Fo*app.t+pi/3);
            cla(app.UIAxes,"reset");
            plot(app.UIAxes, app.t,app.x,"LineWidth",3);title(app.UIAxes, "Señal Original");
            xlabel(app.UIAxes, "Tiempo(s)");ylabel(app.UIAxes, "Amplitud (v)");
        end

        % Callback function
        function SiarribagraficocopiarloabajoButtonPushed(app, event)
            app.UIAxes2.Visible = 'on';
            cla(app.UIAxes2,"reset");
            plot(app.UIAxes2, app.t,app.x,"LineWidth",3);title(app.UIAxes2, "Señal Original");
            xlabel(app.UIAxes2, "Tiempo(s)");ylabel(app.UIAxes2, "Amplitud (v)");
        end

        % Callback function
        function LoquediceFoseponeenF1ButtonPushed(app, event)
            valor_Fo = app.FoEditField.Value();
            app.TcEditField.Value = valor_Fo;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
                        %Parámetros del muestreador
            Fs = 8000; %Frecuencia de muestreo (Hz o muestras/seg)
            T = 1/Fs; %Periodo de muestreo (segundos o tiempo entre muestras)
            tc = app.TcEditField.Value();
            app.t = 0:T:tc-T;
            app.UIAxes.Visible = 'on';
            app.x = cos(2*pi*10*app.t+pi/3);
            cla(app.UIAxes,"reset");
            plot(app.UIAxes, app.t,app.x,"LineWidth",3);title(app.UIAxes, "Señal Capturada");
            xlabel(app.UIAxes, "Tiempo(s)");ylabel(app.UIAxes, "Amplitud");

            tabla = ['aaaaaaaaa'
                'eeeuioeee'
                'iiuuuiiii'
                'oeoooeooo'
                'uiuuuuuuu'
                'aaaaaaaaa'
                'eoeuieeee'
                'iiiiiiuoi'
                'ooouoouoo'
                'uiuoiuuiu'];

            app.UITable.Data = cellstr(num2cell(tabla));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParmetrosdeCapturaTab = uitab(app.TabGroup);
            app.ParmetrosdeCapturaTab.Title = 'Parámetros de Captura';
            app.Image = uiimage(app.ParmetrosdeCapturaTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'microfono.jpg';
            app.TcEditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.TcEditFieldLabel.Text = 'Tc';
            app.TcEditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.TcEditField.Value = 1;
            app.Rp1EditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.Rp1EditFieldLabel.Text = 'Rp1';
            app.Rp1EditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.Rp2EditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.Rp2EditFieldLabel.Text = 'Rp2';
            app.Rp2EditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.Rp3EditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.Rp3EditFieldLabel.Text = 'Rp3';
            app.Rp3EditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.Rp4EditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.Rp4EditFieldLabel.Text = 'Rp4';
            app.Rp4EditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.T_VmsEditFieldLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.T_VmsEditFieldLabel.Text = 'T_V (ms)';
            app.T_VmsEditField = uieditfield(app.ParmetrosdeCapturaTab, 'numeric');
            app.LocutorDropDownLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.LocutorDropDownLabel.Text = 'Locutor';
            app.LocutorDropDown = uidropdown(app.ParmetrosdeCapturaTab);
            app.LocutorDropDown.Items = {'Alumno 1', 'Alumno 2', 'Alumno 3', 'Alumno 4'};
            app.LocutorDropDown.Value = 'Alumno 1';
            app.CargarPuertorButton = uibutton(app.ParmetrosdeCapturaTab, 'push');
            app.CargarPuertorButton.Text = 'Cargar Puertor';
            app.ID_ODropDown = uidropdown(app.ParmetrosdeCapturaTab);
            app.ID_IDropDownLabel = uilabel(app.ParmetrosdeCapturaTab);
            app.ID_IDropDownLabel.Text = 'ID_I';
            app.ID_IDropDown = uidropdown(app.ParmetrosdeCapturaTab);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'N'; 'FRP1'; 'FRP2'; 'FRP3'; 'FRP4'; 'ARP1'; 'ARP2'; 'ARP3'; 'ARP4'};
            app.UITable.RowName = {};
            app.TiempoButton = uibutton(app.UIFigure, 'push');
            app.TiempoButton.Text = 'Tiempo';
            app.EspectrodeMagnitudButton = uibutton(app.UIFigure, 'push');
            app.EspectrodeMagnitudButton.Text = 'Espectro de Magnitud';
            app.EspectrogramaButton = uibutton(app.UIFigure, 'push');
            app.EspectrogramaButton.Text = 'Espectrograma';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.Text = 'Reproducir';
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.UIFigure);
            app.SealDropDown.Items = {'Normal', 'Frp1', 'Frp2', 'Frp3', 'Frp4', 'Arp1', 'Arp2', 'Arp3', 'Arp4', ''};
            app.SealDropDown.Value = 'Normal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end